classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure          % ReHEARsal
        Panel     matlab.ui.container.Panel % STEP 1
        Button    matlab.ui.control.Button  % Select 
        Panel2    matlab.ui.container.Panel
        Label     matlab.ui.control.Label  
        Label3    matlab.ui.control.Label   % Instrumental Track:
        Panel3    matlab.ui.container.Panel
        Label4    matlab.ui.control.Label  
        Label5    matlab.ui.control.Label   % Vocal Track:
        Panel5    matlab.ui.container.Panel % STEP 2
        Button2   matlab.ui.control.Button  % Select 
        Panel6    matlab.ui.container.Panel % STEP 3
        Button3   matlab.ui.control.Button  % Calibrate 
        Panel7    matlab.ui.container.Panel
        Label6    matlab.ui.control.Label   % Welcome to ReHEARsal! 
        Panel8    matlab.ui.container.Panel % CONTROLS
        Button4   matlab.ui.control.Button  % Play
        Button5   matlab.ui.control.Button  % Stop
        LabelKnob matlab.ui.control.Label   % Sensitivity
        Knob      matlab.ui.control.Knob    % [1 10]
    end

    
    properties (Access = private)
        y_left % instrumental track
        y_left_max %max amplitude of instrumental track (for scaling)
        freqSam %y_left Fs
        player %audioplayer 1
        flag1 = 0;  %instrument state
        flag2 = 0;  %vocal state
        flag3 = 0;   %mic state
        flag4 = 0; %play state
        player2 %audiopalyer 2
        z %vocal data for comparison
        peak %microphone peak from cal. for threshold
        peak2 %z peak for threshold
        Fsz %vocal data Fs
        recorder %recording device
        scaler %scaling factor for recorded data
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function choose_song(app)
            
            [file,path] = uigetfile('*.wav','Choose your song!');
            [y,fs] = audioread([path file]);
            app.y_left = y(1:length(y), 1);
            app.y_left_max = max(app.y_left);
            app.freqSam=fs;
            datasound=app.y_left;
            app.player=audioplayer(datasound,app.freqSam);
            app.flag1=1;
            app.Label.Text = file;
            app.Label6.Text = {'Instrumental Track Selected!'; 'Select a Vocal Track!'};
        end

        % Button2 button pushed function
        function load_vocals(app)
            [file2,path2] = uigetfile('*.wav','Select the vocal track!');
            [x, Fsx] = audioread([path2 file2]);
            x_left = x(1:length(x), 1);
            x_left_max = max(x_left);
            x_scale = app.y_left_max/x_left_max;
            x_left = x_left*x_scale;
            freqSam2=Fsx;
            datasound2=x_left;
            app.Fsz = 500;
            [P,Q] = rat(app.Fsz/freqSam2);
            app.player2=audioplayer(datasound2,freqSam2);
            
            app.z = resample(x_left,P,Q);
            app.peak2 = max(app.z);
            z_scaler = 1/app.peak2;
            app.z = app.z*z_scaler;
            
            app.flag2=1;
            app.Label4.Text = file2;
            app.Label6.Text = {'Vocal Track Selected!'; 'Calibrate your microphone!'};
        end

        % Button3 button pushed function
        function calibrate(app)
            app.recorder = audiorecorder(8000, 16, 1);
            app.Label6.Text = {'Please speak'; 'into the microphone'};
            recordblocking(app.recorder, 5);
            app.Label6.Text = 'Done calibrating';
            data = getaudiodata(app.recorder);
            app.peak = max(data);
            app.scaler = 1/app.peak;
            app.flag3 = 1;
        end

        % Button4 button pushed function
        function play(app)
            if(app.flag1 == 1 && app.flag2==1)
                if(app.flag3 == 1)
                    app.flag4 = 1;
                    help_seconds = 5;
                    stopHelpingTime = length(app.y_left)-(app.freqSam*help_seconds);
                    t = 0;
                    threshold_z = app.peak2*0.10;
                    threshold_voice = app.peak*0.10;
                    scale = app.Fsz/app.freqSam;
                    app.Label6.Text = 'Playing';
                    play(app.player);
                    pause(4);
                    while(app.player.isplaying());
                        i = 1;
                        cS = app.player.currentSample;
                        cSz = round(cS*scale);
                        recordblocking(app.recorder, 1);
                        x2 = getaudiodata(app.recorder)*app.scaler;
                        x2 = resample(x2, 1, 16);
                        eSz = cSz+length(x2)-1;
                        x1 = app.z(cSz:eSz,1);
                        x2 = abs(x2);
                        x1 = abs(x1);
                        [envHigh1, envLow1] = envelope(x1,10,'peak');
                        [envHigh2, envLow2] = envelope(x2,10,'peak');
                        x3 = envHigh1;
                        x4 = envHigh2;
                        while(i<=length(x1))
                            if(envHigh1(i) > threshold_z)
                                x3(i) = 1;
                            else
                                x3(i) = 0;
                            end
                            if(envHigh2(i) > threshold_voice)
                                x4(i) = 1;
                            else
                                x4(i) = 0;
                            end
                            i = i+1;
                        end
                        d = dtw(x3, x4);
                        cSnew = app.player.currentSample;
                        %cstop = cSnew + myGui.freqSam*help_seconds;
                        if(d>=(500/app.Knob.Value))
                            t = t+1;
                        else
                            t=0;
                            if(app.player2.isplaying())
                                stop(app.player2);
                            end
                        end
                        if(t>=2)
                            play(app.player2, [cSnew, stopHelpingTime]);
                            t = 0;
                        end
                    end
                end
            end
            
        end

        % Button5 button pushed function
        function stop(app)
            if(app.flag4 == 1)
                if(app.player.isplaying())
                    stop(app.player);
                end
                if(app.player2.isplaying())
                    stop(app.player2);
                end
            
                app.Label6.Text = 'Stopped';
                app.flag4 = 0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'ReHEARsal';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'STEP 1';
            app.Panel.Units = 'pixels';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @choose_song);
            app.Button.Icon = 'icon_tracks.png';
            app.Button.Text = {'Select '; 'InstrumentalTrack'};
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Units = 'pixels';
            app.Label = uilabel(app.Panel2);
            app.Label.Text = '';
            app.Label3 = uilabel(app.Panel2);
            app.Label3.Text = 'Instrumental Track:';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Units = 'pixels';
            app.Label4 = uilabel(app.Panel3);
            app.Label4.Text = '';
            app.Label5 = uilabel(app.Panel3);
            app.Label5.Text = 'Vocal Track:';
            app.Panel5 = uipanel(app.UIFigure);
            app.Panel5.Title = 'STEP 2';
            app.Panel5.Units = 'pixels';
            app.Button2 = uibutton(app.Panel5, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @load_vocals);
            app.Button2.Icon = '1844-200.png';
            app.Button2.Text = {'Select '; 'Vocal Track'};
            app.Panel6 = uipanel(app.UIFigure);
            app.Panel6.Title = 'STEP 3';
            app.Panel6.Units = 'pixels';
            app.Button3 = uibutton(app.Panel6, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @calibrate);
            app.Button3.Icon = '2000px-Speaker_Icon.svg.png';
            app.Button3.Text = {'Calibrate '; 'Microphone'};
            app.Panel7 = uipanel(app.UIFigure);
            app.Panel7.Units = 'pixels';
            app.Label6 = uilabel(app.Panel7);
            app.Label6.Text = {'Welcome to ReHEARsal! '; 'To get started, select an Instrumental Track!'};
            app.Panel8 = uipanel(app.UIFigure);
            app.Panel8.Title = 'CONTROLS';
            app.Panel8.Units = 'pixels';
            app.Button4 = uibutton(app.Panel8, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @play);
            app.Button4.Icon = '81097-round-play-button.png';
            app.Button4.Text = 'Play';
            app.Button5 = uibutton(app.Panel8, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @stop);
            app.Button5.Icon = '680570_button_512x512.png';
            app.Button5.Text = 'Stop';
            app.LabelKnob = uilabel(app.Panel8);
            app.LabelKnob.Text = 'Sensitivity';
            app.Knob = uiknob(app.Panel8, 'continuous');
            app.Knob.Limits = [1 10];
            app.Knob.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Knob.MajorTickLabels = {'', '2', '3', '4', '5', '6', '7', '8', '9', ''};
            app.Knob.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end